classdef Photobooth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        TakeSnapshotButton   matlab.ui.control.Button
        FiltersButtonGroup   matlab.ui.container.ButtonGroup
        NoneButton           matlab.ui.control.RadioButton
        BlackandWhiteButton  matlab.ui.control.RadioButton
        SharpEmbossButton    matlab.ui.control.RadioButton
        NoisyFilterButton    matlab.ui.control.RadioButton
        WeirdColorButton     matlab.ui.control.RadioButton
    end

    methods (Access = private)

        % Button pushed function: TakeSnapshotButton
        function Snapshot(app, event)
            cam=webcam(1);
            pause(1);
            img=snapshot(cam);
            img=imresize(img,[720 1280]);
            imwrite(img,'photobooth_original.jpg');
            imshow('photobooth_original.jpg','Parent',app.UIAxes);
            delete(cam);
            R=img(:,:,1);
            G=img(:,:,2);
            B=img(:,:,3);
        end

        % Selection changed function: FiltersButtonGroup
        function FilterSelection(app, event)
            img=imread('photobooth_original.jpg');
            selectedButton = app.FiltersButtonGroup.SelectedObject;
            if selectedButton == app.NoneButton
                imshow(img,'Parent',app.UIAxes);
                
            elseif selectedButton == app.BlackandWhiteButton
                x=rgb2gray(img);
                bnw=uint8(zeros(720,1280,3));
                bnw(:,:,1)=x;
                bnw(:,:,2)=x;
                bnw(:,:,3)=x;
                imwrite(bnw,'photobooth_bw.jpg');
                imshow('photobooth_bw.jpg','Parent',app.UIAxes);
                
            elseif selectedButton == app.SharpEmbossButton
                hemboss=[-2 -1 0; -1 1 1; 0 1 2];
                hsharpen=[0 -1 0; -1 5 -1; 0 -1 0];
                embossed=imfilter(img,hemboss);
                emb_sharp=imfilter(embossed,hsharpen);
                imwrite(emb_sharp,'photobooth_emsh.jpg');
                imshow('photobooth_emsh.jpg','Parent',app.UIAxes);
                
            elseif selectedButton == app.NoisyFilterButton
                noisy=imnoise(img,'gaussian',0.4);
                med1=medfilt2(noisy(:,:,1));
                med2=medfilt2(noisy(:,:,2));
                med3=medfilt2(noisy(:,:,3));
                med=cat(3,med1,med2.*1.1,med3.*1.3);
                imwrite(med,'photobooth_noise.jpg');
                imshow('photobooth_noise.jpg','Parent',app.UIAxes);
                
            elseif selectedButton == app.WeirdColorButton
                adjimg=decorrstretch(imadjust(img,[0.3 0.5],[]));
                imwrite(adjimg,'photobooth_coloradjust.jpg');
                imshow('photobooth_coloradjust.jpg','Parent',app.UIAxes);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.TakeSnapshotButton = uibutton(app.UIFigure, 'push');
            app.TakeSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @Snapshot, true);
            app.TakeSnapshotButton.Text = 'Take Snapshot';
            app.FiltersButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterSelection, true);
            app.FiltersButtonGroup.Title = 'Filters';
            app.NoneButton = uiradiobutton(app.FiltersButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.BlackandWhiteButton = uiradiobutton(app.FiltersButtonGroup);
            app.BlackandWhiteButton.Text = 'Black and White';
            app.SharpEmbossButton = uiradiobutton(app.FiltersButtonGroup);
            app.SharpEmbossButton.Text = 'SharpEmboss';
            app.NoisyFilterButton = uiradiobutton(app.FiltersButtonGroup);
            app.NoisyFilterButton.Text = 'Noisy Filter';
            app.WeirdColorButton = uiradiobutton(app.FiltersButtonGroup);
            app.WeirdColorButton.Text = 'Weird Color';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Photobooth

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end